classdef Market_Price_Estimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        ImportFileButton             matlab.ui.control.Button
        MarketPriceEstimatorLabel    matlab.ui.control.Label
        DataExpoLabel                matlab.ui.control.Label
        Panel_14                     matlab.ui.container.Panel
        QuantityEditField            matlab.ui.control.NumericEditField
        QuantityLabel                matlab.ui.control.Label
        CommodityDropDown            matlab.ui.control.DropDown
        CommodityLabel_2             matlab.ui.control.Label
        SubmitButton                 matlab.ui.control.Button
        Label_4                      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        MarketPerformanceTab         matlab.ui.container.Tab
        Panel_4                      matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout11                 matlab.ui.container.GridLayout
        Label_3                      matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        NodataLabel_3                matlab.ui.control.Label
        NodataLabel_5                matlab.ui.control.Label
        NodataLabel_6                matlab.ui.control.Label
        NodataLabel_4                matlab.ui.control.Label
        NodataLabel                  matlab.ui.control.Label
        NodataLabel_2                matlab.ui.control.Label
        StatesWithHighestAveragePricelast5yearsLabel  matlab.ui.control.Label
        AlltimeNationalMarketPerformanceLabel  matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        CommodityLabel               matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        RegionalPerformanceTab       matlab.ui.container.Tab
        Panel_10                     matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        YearDropDown                 matlab.ui.control.DropDown
        YearDropDownLabel            matlab.ui.control.Label
        UIAxes6                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        Panel_11                     matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        PriceRangeAcrossStatesLabel  matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        UIAxes7                      matlab.ui.control.UIAxes
        PriceTrendTab                matlab.ui.container.Tab
        Panel_12                     matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        StateDropDown                matlab.ui.control.DropDown
        StateDropDown_4Label         matlab.ui.control.Label
        UIAxes4                      matlab.ui.control.UIAxes
        Panel_9                      matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        StateDropDown_2              matlab.ui.control.DropDown
        StateDropDown_2Label         matlab.ui.control.Label
        UIAxes5                      matlab.ui.control.UIAxes
        RegionalPriceTrendOverTheyearsLabel  matlab.ui.control.Label
        ForecastTab                  matlab.ui.container.Tab
        Panel_6                      matlab.ui.container.Panel
        Panel_15                     matlab.ui.container.Panel
        NoDataLabel                  matlab.ui.control.Label
        forecastsum                  matlab.ui.control.Label
        Panel_13                     matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        StateDropDownLabel           matlab.ui.control.Label
        StateDropDown_3              matlab.ui.control.DropDown
        UIAxes10                     matlab.ui.control.UIAxes
        UIAxes9                      matlab.ui.control.UIAxes
        Panel_7                      matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        StateLabel                   matlab.ui.control.Label
        CityLabel                    matlab.ui.control.Label
        PricePerUnitLabel            matlab.ui.control.Label
        NodataLabel_15               matlab.ui.control.Label
        NodataLabel_14               matlab.ui.control.Label
        NodataLabel_13               matlab.ui.control.Label
        NodataLabel_12               matlab.ui.control.Label
        NodataLabel_11               matlab.ui.control.Label
        NodataLabel_10               matlab.ui.control.Label
        NodataLabel_9                matlab.ui.control.Label
        NodataLabel_8                matlab.ui.control.Label
        NodataLabel_7                matlab.ui.control.Label
        CitiesWithHighestAverageForecastedPriceLabel  matlab.ui.control.Label
        forecastintro                matlab.ui.control.Label
        EstimatedPricesLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data % Description
        forecast
    end
    
    methods (Access = private)
        
        function startupFunction(app,dialogue)
            % app.data = fillmissing(app.data, "nearest");
            app.data = app.data(app.data.price > 0, :);

            getCommodity = groupsummary(app.data,"commodity");
            app.CommodityDropDown.Items = ["none";getCommodity.commodity];

            dialogue.Value = .70;
            dialogue.Message = "Organising";
            app.CommodityDropDown.Enable = "on";
            printDataExpo(app);

            app.data = getPricePerUnit(app,app.data);
        end
    
        function [data] = getPricePerUnit(~,data) 
            [values, ~] = regexp(data.unit, '([0-9]+\.?[0-9]*)?(\D+)?','tokens','once');

            for i = 1:height(values)
                if isempty(values{i}{1})
                    values{i}{1} = "1";
                end
            end
            % units = num2cell(units);
            units = cellfun(@(x)strtrim(lower(x{2})),values,'UniformOutput',false);
            values = cellfun(@(x)str2double(x{1}),values);
            
            standardizedQuantities = zeros(size(values));
            
            kgMask = strcmp(units, 'kg');
            gMask = strcmp(units, 'g');
            mlMask = strcmp(units, 'ml');
            lMask = strcmp(units, 'l');
            tubers = strcmp(units, 'tubers');
            unit = strcmp(units, 'unit');
            pcs = strcmp(units, 'pcs');
            
            standardizedQuantities(kgMask) = values(kgMask);
            standardizedQuantities(gMask) = values(gMask)/1000;
            standardizedQuantities(mlMask) = values(mlMask)/1000;
            standardizedQuantities(lMask) = values(lMask);
            standardizedQuantities(tubers) = values(tubers);
            standardizedQuantities(unit) = values(unit);
            standardizedQuantities(pcs) = values(pcs);
            priceperunit = data.price ./ standardizedQuantities;

            priceperunit = str2double(compose('%.2f',priceperunit));
            data = [data table(standardizedQuantities) table(units) table(priceperunit)];

        end
    
        function printDataExpo(app)
               Data = app.data;
               numcommodity = height(groupsummary(Data,"commodity"));
               Data.date = Data.date.Year;
               span = groupsummary(Data,"date");
               start = span(1,1);
               start = start.date;
               untill = span(end,1);
               untill = untill.date;
               numstates = height(groupsummary(Data,"state"));
               app.DataExpoLabel.Text =  "The dataset imported contains a record of "+numcommodity + " different commodities sold across "+ numstates +" states in the country."+newline+"The data is recorded over a period of "+start+" to "+untill+".";
         end
    
        function StatesWhighestavgprice(app,cropData)
            cropData.date = cropData.date.Year;
            g = groupsummary(cropData,"date");
            if(height(g) > 5)
               f = g.date(1:5);
            else
               f =  g.date(1:height(g));
            end
            
            cropData = groupsummary(cropData,{'date','state'},"mean",'priceperunit');
            
            cropData = cropData(cropData.date <= f(height(f)),:);
            
            cropData = groupsummary(cropData,"state","mean","mean_priceperunit"); 
            cropData.mean_mean_priceperunit = str2double(compose('%.2f',cropData.mean_mean_priceperunit));
            cropData = sortrows(cropData,"mean_mean_priceperunit","descend");
            cropData(4:end,:) = [];
            plabel = [app.NodataLabel,app.NodataLabel_3,app.NodataLabel_5];
            slabel = [app.NodataLabel_2, app.NodataLabel_4, app.NodataLabel_6];
            for i=1:height(cropData)
                plabel(i).Text = ""+char(8358)+cropData.mean_mean_priceperunit(i)+"";
                slabel(i).Text = ""+cropData.state(i)+"";
            end
        end
    
        function printIntro(app,data)
             
           unit = string(data.units(1));
           commodity = string(data.commodity(1));
           nostates = height(groupsummary(data,"state"));
           avgprice = mean(data(:,"priceperunit"));
           avgprice = avgprice.priceperunit;
           avgprice = str2double(compose('%.2f',avgprice));
           totquantities = sum(data(:,"standardizedQuantities"));
           totquantities = totquantities.standardizedQuantities;
           date = table(data.date.Year);
           date = groupsummary(date,"Var1");
           untill = date(end,1);
           untill = untill.Var1;
           start = date(1,1);
           start = start.Var1;
           app.Label_3.Text =  "From the data provided, "+commodity + " is sold in "+ nostates +" states within the country, at a national average price of " +char(8358)+avgprice+" per "+upper(unit)+", The total amount of "+commodity+" sold is "+totquantities+" "+upper(unit)+" since "+start+" to "+untill+" ";
                        
        end
    
        function preedictable = predictPrice(app,cropData)
            % Convert Date column to datetime
            cropData.date = datetime(cropData.date, 'InputFormat', 'yyyy-MM-dd');
            
            % Extract Year, Month, and Day as features
            cropData.Year = year(cropData.date);
            cropData.Month = month(cropData.date);
            cropData.Day = day(cropData.date);
            
            % Encode Categorical Variables (State and City)
            cropData.state = categorical(cropData.state);
            cropData.city = categorical(cropData.city);
            
            % Convert categorical variables to numerical using dummy variables
            X_state = dummyvar(cropData.state); 
            X_city = dummyvar(cropData.city);
            
            % Combine all features into a matrix
            X = [cropData.Year, cropData.Month, cropData.Day, cropData.standardizedQuantities, X_state, X_city]; 
            y = cropData.priceperunit;  % Target variable
            
            % Train a Random Forest Regressor with 100 trees
            rfModel = fitrensemble(X, y, 'Method', 'Bag', 'NumLearningCycles', 200);

            
            dataend = cropData.date(end);
            states = groupsummary(cropData,{'state','city'});
            states = removevars(states, "GroupCount");
            predicttill = cropData.date(end)+calyears(1);
            date = (dataend+1:predicttill)';
            tblz = height(date)*height(states);
            
            app.forecastintro.Text = "Based on the dataset uploaded, a market forcast has been made for "+cropData.commodity(1)+newline+" The forecast is made over a period of "+ string(dataend+1) +" to "+ string(predicttill)+".";

            preedictable = table('Size',[tblz 7],'VariableTypes',{'double','double','double','string','string','double','double'}, ...
            'VariableNames',{'Year','Month', 'Day','state','city','quantity','price'});
            row = 1;
                for i= 1:height(date) 
                    for k = 1:height(states)
                        newdata = struct('quantity',50,'state','Gombe','city','Akko');
                        datarow = struct('Year',date.Year(i),'Month', date.Month(i), 'Day',date.Day(i),'state',states.state(k),'city',states.city(k),'quantity',newdata.quantity,'price',0);
                        datarow = struct2table(datarow);
                        preedictable(row,:) = datarow;
                        % 
                        row = row+1;
                    end
            
                end
          
            % class(cropData.state)
            dummystate = dummyvar(categorical(preedictable.state));
            dummycity = dummyvar(categorical(preedictable.city));
            % 
            % X = [cropData.Year, cropData.Month, cropData.Day, cropData.standardizedQuantities, X_state, X_city]; 
            X_test = [preedictable.Year,preedictable.Month,preedictable.Day, preedictable.quantity, dummystate, dummycity];
            y_pred = predict(rfModel, X_test);
            preedictable.price = y_pred;
           
            preedictable = groupsummary(preedictable,{'Year','Month','state','city'},"mean","price");
  
        end
    
        function plotboxdistribution(app,data)
            xplot = categorical(data.state);
            yplot = data.priceperunit;
            boxplot(app.UIAxes7,yplot,xplot);
            label = "Price Per Unit("+char(8358)+")";
            ylabel(app.UIAxes7,label);
        end
    
    
        function plotPriceDistribution(app,data)
            % data.date =dateshift(datetime("6-"+data.date.Month+"-"+data.date.Year+"",InputFormat="dd-MM-uuuu"),'end','month');
            data.date = data.date.Year;
            yearlyData = groupsummary(data,"date","sum","priceperunit");
            yearlyData.sum_priceperunit = str2double(compose('%.2f',yearlyData.sum_priceperunit));
            
            xplot = yearlyData.date;
            yplot = yearlyData.sum_priceperunit;
            bar(app.UIAxes,xplot,yplot,FaceColor=[0.60,0.80,0.45]);
            app.UIAxes.YAxis.Exponent = 0;
            label = "Price("+char(8358)+")";
            ylabel(app.UIAxes,label);
        end

        function plotquantitydistribution(app,data)
            data.date = data.date.Year;
            yearlyData = groupsummary(data,"date","sum","standardizedQuantities");
            yearlyData.sum_standardizedQuantities = str2double(compose('%.2f',yearlyData.sum_standardizedQuantities));
            
            xplot = yearlyData.date;
            yplot = yearlyData.sum_standardizedQuantities;
            bar(app.UIAxes3,xplot,yplot,FaceColor=[0.60,0.80,0.45]);
            
            ylabel(app.UIAxes3,"Total Quantities("+data.units(1)+")");
            app.UIAxes3.YAxis.Exponent = 0;
        end

        function plotPriceByRegion(app,data,year)
            value = app.YearDropDown.Value;
            data.date = data.date.Year; 
            year = str2double(year);
            averageRegionalPrice = data(data.date == year,:);
            averageRegionalPrice = groupsummary(averageRegionalPrice,"state","mean","priceperunit");
            a =categorical(averageRegionalPrice.state);
            reset(app.UIAxes2);
            averageRegionalPrice.mean_priceperunit = str2double(compose('%.2f',averageRegionalPrice.mean_priceperunit));
            
            xplot = a;
            yplot = averageRegionalPrice.mean_priceperunit;
            bar(app.UIAxes2,xplot,yplot,FaceColor=[0.60,0.80,0.45]);
            title(app.UIAxes2,"Average Regional Price for "+value+"");
             label = "Average Price("+char(8358)+")";
             ylabel(app.UIAxes2,label);
        end

        function plotQuantityByRegion(app,data,year)
            value = app.YearDropDown.Value;
            data.date = data.date.Year; 
            year = str2double(year);
            averageRegionalQuantity = data(data.date == year,:);
            averageRegionalQuantity = groupsummary(averageRegionalQuantity,"state","sum","priceperunit");
            a =categorical(averageRegionalQuantity.state);
            reset(app.UIAxes6);
            averageRegionalQuantity.sum_priceperunit = str2double(compose('%.2f',averageRegionalQuantity.sum_priceperunit));
            
            xplot = a;
            yplot = averageRegionalQuantity.sum_priceperunit;
            bar(app.UIAxes6,xplot,yplot,FaceColor=[0.60,0.80,0.45]);
            title(app.UIAxes6,"Total Regional quantity of "+value+" sold");
            ylabel(app.UIAxes6,"Total Quantities("+data.units(1)+")");
        end

        function plotpriceovertime(app,data,state) 
            data.date = data.date.Year;
            averageYearlyPrice = data(data.state == ""+state+"",:);
            averageYearlyPrice = groupsummary(averageYearlyPrice,"date","mean","priceperunit");
            averageYearlyPrice.mean_priceperunit = str2double(compose('%.2f',averageYearlyPrice.mean_priceperunit));
            
            xplot = averageYearlyPrice.date;
            yplot = averageYearlyPrice.mean_priceperunit;
            plot(app.UIAxes4,xplot,yplot,"-o","LineWidth",1,"MarkerSize",6,"MarkerFaceColor","g","Color",[0.60,0.80,0.45]);
            text(app.UIAxes4,xplot,yplot,string(yplot))
            label = "Average Price Per Unit("+char(8358)+")";
            ylabel(app.UIAxes4,label);
        end

        function dropDownItems(app,cropData)
            getState = groupsummary(cropData,"state");
            app.StateDropDown.Items = ""+getState.state+"";
            app.StateDropDown_3.Items = ""+getState.state+"";
            app.StateDropDown_2.Items = ""+getState.state+"";

            % % Summary all the days to have no repetitions
            getYear = groupsummary(cropData,"date");
            getYear.date = getYear.date.Year;
            getYear = groupsummary(getYear,"date");
            % % Add the date values to the YearDropDown
            app.YearDropDown.Items = ""+getYear.date+"";
        end
        
        function forecastpricebystate(app,state)
            
            a = app.forecast(app.forecast.state == state,:);
            b = groupsummary(a,'city','mean','mean_price');
            reset(app.UIAxes9);
            bar(app.UIAxes9,b.city,b.mean_mean_price)
            label = "Price Per Unit("+char(8358)+")";
            ylabel(app.UIAxes9,label);
        end


        function plotseasonalpricevariationbystate(app,cropData,state)
            % Ensure the date is correctly parsed
            cropData.date = datetime(cropData.date, 'InputFormat', 'yyyy-MM-dd');
            
            % Extract the month from the date
            cropData.date = month(cropData.date);
             %Filter out a single state
            cropData = cropData(cropData.state == ""+state+"",:);
            % Group data by Month, calculating the mean price per unit sold
            grpData = groupsummary(cropData,'date', 'mean', 'priceperunit');
            grpData.mean_priceperunit = str2double(compose('%.2f',grpData.mean_priceperunit));
            
            % months = ["Jan" "Feb" "Mar" "Apr" "May" "June" "July" "Aug" "Sep" "Oct" "Nov" "Dec"];  % Months from January to December
            
            yplot = grpData.mean_priceperunit;
            xplot = grpData.date;
            bar(app.UIAxes5,xplot,yplot,FaceColor=[0.60,0.80,0.45]);
            label = "Price("+char(8358)+")";
            ylabel(app.UIAxes5,label);
        end
       

        function citiesWhighestForecast(app,data)
            data = groupsummary(data,{'state','city'},"mean",'mean_price');
            data.mean_mean_price = str2double(compose('%.2f',data.mean_mean_price));
            
            data = sortrows(data,"mean_mean_price","descend");
            if(height(data) > 3)
                data = data(1:3,:);
            else
               data = data(height(data),:);
            end
            plabel = [app.NodataLabel_10,app.NodataLabel_11,app.NodataLabel_12];
            clabel = [app.NodataLabel_7,app.NodataLabel_8,app.NodataLabel_9];
            slabel = [app.NodataLabel_13, app.NodataLabel_14, app.NodataLabel_15];
            for i=1:height(data)
                plabel(i).Text = ""+char(8358)+data.mean_mean_price(i)+"";
                clabel(i).Text = ""+data.city(i)+"";
                slabel(i).Text = ""+data.state(i)+"";
            end
            app.forecastsum.Text = "Selling at "+data.city(1)+", "+data.state(1)+" state for the highest price average of "+char(8358)+data.mean_mean_price(1)+newline+ app.QuantityEditField.Value +app.Label_4.Text+" of "+ app.CommodityDropDown.Value +" will be sold at:";
            app.NoDataLabel.Text = ""+char(8358)+str2double(compose('%.2f',app.QuantityEditField.Value*data.mean_mean_price(1)))+"";
            
        end

        function forcastvariability(app,data)
            xplot = categorical(data.state);
            yplot = data.mean_price;
            boxplot(app.UIAxes10,yplot,xplot);
            label = "Price Per Unit("+char(8358)+")";
            ylabel(app.UIAxes10,label);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            try
                [file,path] = uigetfile('*.csv');
                app.data = readtable(fullfile(path,file));
            catch
                app.DataExpoLabel.Text = "Error Inporting file, Try again";
                app.CommodityDropDown.Enable = "off";
                return;
            end
            if isequal(file, 0)
                app.DataExpoLabel.Text = "No file selected";
                return;
            end
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Importing CSV');
            d.Message = 'Reading Your data';
            d.Value = .33;
            columnnames = app.data.Properties.VariableNames ;
            column = {'date','state','city','commodity','price','unit'};
            
            missing = setdiff(column,columnnames);
            if isempty(missing)
            else
                app.DataExpoLabel.Text = "File cannot be accepted, Try again";
                app.CommodityDropDown.Enable = "off";
                return;
            end
            d.Value = .67;
            d.Message = 'Processing Your data';
            startupFunction(app,d);
            d.Value = 1;
            d.Message = 'Finishing';
            pause(2);
            close(d);
        end

        % Value changed function: CommodityDropDown
        function CommodityDropDownValueChanged(app, event)
             value = app.CommodityDropDown.Value;
             if(value == "none")
                 app.SubmitButton.Enable ="off";
                 app.QuantityEditField.Enable = "off";
                 return;
             end
            cropData = app.data(app.data.commodity == ""+value+"", :);
            
            % Update Unit Label
            app.Label_4.Text = upper(string(cropData.units(1)));
            
            
            app.SubmitButton.Enable ="on";
            app.QuantityEditField.Enable = "on";
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            dialog = uiprogressdlg(app.UIFigure,"Indeterminate","on","Title","Plotting Data","Message","This will take a few second");
            app.SubmitButton.Enable ="off";
            value = app.CommodityDropDown.Value;
            if(value == "none")
                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes5);
                cla(app.UIAxes6);
                cla(app.UIAxes7);
                cla(app.UIAxes8);
                cla(app.UIAxes9);
                
                app.StateDropDown.Enable = "off";
                app.StateDropDown_2.Enable = "off";
                app.StateDropDown_3.Enable = "off";
                app.YearDropDown.Enable = "off";
                app.QuantityEditField.Enable = "off";
                app.SubmitButton.Enable ="off";
                return;
            end


            cropData = app.data(app.data.commodity == ""+value+"", :);
            dropDownItems(app,cropData);

            % Show Highest Priced States
            StatesWhighestavgprice(app,cropData);
            
            % Show Market Intro
            printIntro(app,cropData);

            
            result = predictPrice(app,cropData);
            % 
            % Update commodity Label
            app.CommodityLabel.Text = "Commodity: "+value+"" ;
            
            % Show Highest Priced States
            % StatesWhighestavgprice(app,cropData);
            
            % Show Market Intro
            % printIntro(app,cropData);
            
            plotboxdistribution(app,cropData);
            

            plotPriceDistribution(app,cropData);
            

            plotquantitydistribution(app,cropData);


            value = app.StateDropDown_3.Value;
            plotpriceovertime(app,cropData,value);
            
            
            value = app.YearDropDown.Value; 
            plotPriceByRegion(app,cropData,value);
            plotQuantityByRegion(app,cropData,value);


            state = app.StateDropDown_2.Value;
            plotseasonalpricevariationbystate(app,cropData,state);


            app.StateDropDown.Enable = "on";
            app.StateDropDown_2.Enable = "on";
            app.StateDropDown_3.Enable = "on";
            app.YearDropDown.Enable = "on";
            app.QuantityEditField.Enable = "on";
            pause(2);
            close(dialog);
  
            % result = fetchOutputs(result);
            result = removevars(result, "GroupCount");
            app.forecast = result;
            value = app.StateDropDown_3.Value;

            forecastpricebystate(app,value);
            
           
            citiesWhighestForecast(app,result);
            forcastvariability(app,result);
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            value = app.CommodityDropDown.Value;
   
            cropData = app.data(app.data.commodity == ""+value+"", :);
            value = app.YearDropDown.Value;
            plotPriceByRegion(app,cropData,value);

            plotQuantityByRegion(app,cropData,value);
        end

        % Value changed function: StateDropDown
        function StateDropDownValueChanged(app, event)
            
            value = app.CommodityDropDown.Value;
            cropData = app.data(app.data.commodity == ""+value+"", :);

            value = app.StateDropDown.Value;
            plotpriceovertime(app,cropData,value);
        end

        % Value changed function: StateDropDown_2
        function StateDropDown_2ValueChanged(app, event)
            
            value = app.CommodityDropDown.Value;
            cropData = app.data(app.data.commodity == ""+value+"", :);
            
            state = app.StateDropDown_2.Value;
            plotseasonalpricevariationbystate(app,cropData,state);
        end

        % Value changed function: StateDropDown_3
        function StateDropDown_3ValueChanged(app, event)
            value = app.StateDropDown_3.Value;
            forecastpricebystate(app,value)
        end

        % Value changed function: QuantityEditField
        function QuantityEditFieldValueChanged(app, event)
            
            app.SubmitButton.Enable ="on";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {557, 557};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {334, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout10 = uigridlayout(app.LeftPanel);
            app.Panel_14 = uipanel(app.GridLayout10);
            app.Label_4 = uilabel(app.Panel_14);
            app.Label_4.Text = '';
            app.SubmitButton = uibutton(app.Panel_14, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Enable = 'off';
            app.SubmitButton.Text = 'Submit';
            app.CommodityLabel_2 = uilabel(app.Panel_14);
            app.CommodityLabel_2.Text = 'Commodity:';
            app.CommodityDropDown = uidropdown(app.Panel_14);
            app.CommodityDropDown.Items = {};
            app.CommodityDropDown.ValueChangedFcn = createCallbackFcn(app, @CommodityDropDownValueChanged, true);
            app.CommodityDropDown.Enable = 'off';
            app.CommodityDropDown.Value = {};
            app.QuantityLabel = uilabel(app.Panel_14);
            app.QuantityLabel.Text = 'Quantity:';
            app.QuantityEditField = uieditfield(app.Panel_14, 'numeric');
            app.QuantityEditField.Limits = [0 Inf];
            app.QuantityEditField.ValueChangedFcn = createCallbackFcn(app, @QuantityEditFieldValueChanged, true);
            app.QuantityEditField.Enable = 'off';
            app.QuantityEditField.Value = 1;
            app.DataExpoLabel = uilabel(app.GridLayout10);
            app.DataExpoLabel.Text = 'DataExpo';
            app.MarketPriceEstimatorLabel = uilabel(app.GridLayout10);
            app.MarketPriceEstimatorLabel.Text = 'Market Price Estimator';
            app.ImportFileButton = uibutton(app.GridLayout10, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.MarketPerformanceTab = uitab(app.TabGroup);
            app.MarketPerformanceTab.Title = 'Market Performance';
            app.Panel_3 = uipanel(app.MarketPerformanceTab);
            app.UIAxes3 = uiaxes(app.Panel_3);
            title(app.UIAxes3, 'Yearly Total Quantities')
            xlabel(app.UIAxes3, 'Years')
            ylabel(app.UIAxes3, 'Total Quantities')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, 'Yearly Price Total')
            xlabel(app.UIAxes, 'Years')
            ylabel(app.UIAxes, 'Price')
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = 'This dashboard provides an overview into the state of the market by showing the relationship between the quantities sold and their prices over the years';
            app.CommodityLabel = uilabel(app.Panel_3);
            app.CommodityLabel.Text = 'Commodity: ';
            app.AlltimeNationalMarketPerformanceLabel = uilabel(app.MarketPerformanceTab);
            app.AlltimeNationalMarketPerformanceLabel.Text = 'All-time National Market Performance';
            app.Panel_4 = uipanel(app.MarketPerformanceTab);
            app.GridLayout2 = uigridlayout(app.Panel_4);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.StatesWithHighestAveragePricelast5yearsLabel = uilabel(app.GridLayout3);
            app.StatesWithHighestAveragePricelast5yearsLabel.Text = 'States With Highest Average Price (last 5 years)';
            app.NodataLabel_2 = uilabel(app.GridLayout3);
            app.NodataLabel_2.Text = 'No data';
            app.NodataLabel = uilabel(app.GridLayout3);
            app.NodataLabel.Text = 'No data';
            app.NodataLabel_4 = uilabel(app.GridLayout3);
            app.NodataLabel_4.Text = 'No data';
            app.NodataLabel_6 = uilabel(app.GridLayout3);
            app.NodataLabel_6.Text = 'No data';
            app.NodataLabel_5 = uilabel(app.GridLayout3);
            app.NodataLabel_5.Text = 'No data';
            app.NodataLabel_3 = uilabel(app.GridLayout3);
            app.NodataLabel_3.Text = 'No data';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout11 = uigridlayout(app.Panel_2);
            app.Label_3 = uilabel(app.GridLayout11);
            app.Label_3.Text = 'No data';
            app.RegionalPerformanceTab = uitab(app.TabGroup);
            app.RegionalPerformanceTab.Title = 'Regional Performance';
            app.Panel_11 = uipanel(app.RegionalPerformanceTab);
            app.GridLayout7 = uigridlayout(app.Panel_11);
            app.UIAxes7 = uiaxes(app.GridLayout7);
            xlabel(app.UIAxes7, 'State')
            ylabel(app.UIAxes7, 'Price per unit')
            zlabel(app.UIAxes7, 'Z')
            app.TextArea = uitextarea(app.GridLayout7);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This chart shows the range of prices for the selected commodity across states within the country:'; '- Wide boxes indicates higher price versatility within the state.'; '- Outliers (Dots outside whiskers) reveals extreme prices.'; '- Medians are represented by the horizontal lines within the boxes.'; '- Comparing medians helps to identify states with typically higher prices.'};
            app.PriceRangeAcrossStatesLabel = uilabel(app.GridLayout7);
            app.PriceRangeAcrossStatesLabel.Text = 'Price Range Across States';
            app.Panel_10 = uipanel(app.RegionalPerformanceTab);
            app.GridLayout4 = uigridlayout(app.Panel_10);
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Average Regional Price')
            xlabel(app.UIAxes2, 'State')
            ylabel(app.UIAxes2, 'Average Price')
            app.UIAxes2.XTick = [0 0.5 1];
            app.UIAxes6 = uiaxes(app.GridLayout4);
            title(app.UIAxes6, 'Total Regional Quantity Sold')
            xlabel(app.UIAxes6, 'State')
            ylabel(app.UIAxes6, 'Total Quantity')
            zlabel(app.UIAxes6, 'Z')
            app.YearDropDownLabel = uilabel(app.GridLayout4);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.GridLayout4);
            app.YearDropDown.Items = {''};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = '';
            app.PriceTrendTab = uitab(app.TabGroup);
            app.PriceTrendTab.Title = 'Price Trend';
            app.RegionalPriceTrendOverTheyearsLabel = uilabel(app.PriceTrendTab);
            app.RegionalPriceTrendOverTheyearsLabel.Text = 'Regional Price Trend Over The years';
            app.Panel_9 = uipanel(app.PriceTrendTab);
            app.GridLayout6 = uigridlayout(app.Panel_9);
            app.UIAxes5 = uiaxes(app.GridLayout6);
            title(app.UIAxes5, 'Seasonal Price Variation By State')
            xlabel(app.UIAxes5, 'Month')
            ylabel(app.UIAxes5, 'Price')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.UIAxes5.XTickLabel = {'Jan'; 'Feb'; 'Mar'; 'Apr'; 'May'; 'June'; 'July'; 'Aug'; 'Sep'; 'Oct'; 'Nov'; 'Dec'};
            app.StateDropDown_2Label = uilabel(app.GridLayout6);
            app.StateDropDown_2Label.Text = 'State';
            app.StateDropDown_2 = uidropdown(app.GridLayout6);
            app.StateDropDown_2.Items = {''};
            app.StateDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StateDropDown_2ValueChanged, true);
            app.StateDropDown_2.Enable = 'off';
            app.StateDropDown_2.Value = '';
            app.Panel_12 = uipanel(app.PriceTrendTab);
            app.GridLayout5 = uigridlayout(app.Panel_12);
            app.UIAxes4 = uiaxes(app.GridLayout5);
            xlabel(app.UIAxes4, 'Years')
            ylabel(app.UIAxes4, 'Average Price Per Unit')
            zlabel(app.UIAxes4, 'Z')
            app.StateDropDown_4Label = uilabel(app.GridLayout5);
            app.StateDropDown_4Label.Text = 'State';
            app.StateDropDown = uidropdown(app.GridLayout5);
            app.StateDropDown.Items = {''};
            app.StateDropDown.ValueChangedFcn = createCallbackFcn(app, @StateDropDownValueChanged, true);
            app.StateDropDown.Enable = 'off';
            app.StateDropDown.Value = '';
            app.ForecastTab = uitab(app.TabGroup);
            app.ForecastTab.Title = 'Forecast';
            app.Panel_6 = uipanel(app.ForecastTab);
            app.EstimatedPricesLabel = uilabel(app.Panel_6);
            app.EstimatedPricesLabel.Text = 'Estimated Prices';
            app.forecastintro = uilabel(app.Panel_6);
            app.forecastintro.Text = 'No Data';
            app.Panel_7 = uipanel(app.Panel_6);
            app.GridLayout9 = uigridlayout(app.Panel_7);
            app.CitiesWithHighestAverageForecastedPriceLabel = uilabel(app.GridLayout9);
            app.CitiesWithHighestAverageForecastedPriceLabel.Text = 'Cities With Highest Average Forecasted Price';
            app.NodataLabel_7 = uilabel(app.GridLayout9);
            app.NodataLabel_7.Text = 'No data';
            app.NodataLabel_8 = uilabel(app.GridLayout9);
            app.NodataLabel_8.Text = 'No data';
            app.NodataLabel_9 = uilabel(app.GridLayout9);
            app.NodataLabel_9.Text = 'No data';
            app.NodataLabel_10 = uilabel(app.GridLayout9);
            app.NodataLabel_10.Text = 'No data';
            app.NodataLabel_11 = uilabel(app.GridLayout9);
            app.NodataLabel_11.Text = 'No data';
            app.NodataLabel_12 = uilabel(app.GridLayout9);
            app.NodataLabel_12.Text = 'No data';
            app.NodataLabel_13 = uilabel(app.GridLayout9);
            app.NodataLabel_13.Text = 'No data';
            app.NodataLabel_14 = uilabel(app.GridLayout9);
            app.NodataLabel_14.Text = 'No data';
            app.NodataLabel_15 = uilabel(app.GridLayout9);
            app.NodataLabel_15.Text = 'No data';
            app.PricePerUnitLabel = uilabel(app.GridLayout9);
            app.PricePerUnitLabel.Text = 'Price Per Unit';
            app.CityLabel = uilabel(app.GridLayout9);
            app.CityLabel.Text = 'City';
            app.StateLabel = uilabel(app.GridLayout9);
            app.StateLabel.Text = 'State';
            app.Panel_13 = uipanel(app.Panel_6);
            app.GridLayout8 = uigridlayout(app.Panel_13);
            app.UIAxes9 = uiaxes(app.GridLayout8);
            title(app.UIAxes9, 'Forecasted Regional Price')
            xlabel(app.UIAxes9, 'Cities')
            ylabel(app.UIAxes9, 'Price per unit')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.GridLayout8);
            title(app.UIAxes10, 'Forecasted Price Range')
            xlabel(app.UIAxes10, 'State')
            ylabel(app.UIAxes10, 'Price per unit')
            zlabel(app.UIAxes10, 'Z')
            app.StateDropDown_3 = uidropdown(app.GridLayout8);
            app.StateDropDown_3.Items = {''};
            app.StateDropDown_3.ValueChangedFcn = createCallbackFcn(app, @StateDropDown_3ValueChanged, true);
            app.StateDropDown_3.Enable = 'off';
            app.StateDropDown_3.Value = '';
            app.StateDropDownLabel = uilabel(app.GridLayout8);
            app.StateDropDownLabel.Text = 'State';
            app.Panel_15 = uipanel(app.Panel_6);
            app.forecastsum = uilabel(app.Panel_15);
            app.forecastsum.Text = 'No Data';
            app.NoDataLabel = uilabel(app.Panel_15);
            app.NoDataLabel.Text = 'No Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Market_Price_Estimator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end